classdef sigPreProcessAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectFolderButton              matlab.ui.control.Button
        CurrentFolderEditField          matlab.ui.control.EditField
        CurrentFolderEditFieldLabel     matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel  matlab.ui.control.Label
        EEGPreprocessingAnalyzerv1Label  matlab.ui.control.Label
        FeatureGenerationPanel          matlab.ui.container.Panel
        PostProcessingPanel             matlab.ui.container.Panel
        PreProcessingPanel              matlab.ui.container.Panel
        FilesPanel                      matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        LoadSelectedSETFileButton       matlab.ui.control.Button
        LoadedSETfileEEGStructureLabel  matlab.ui.control.Label
        AvailableSETfilesinCurrentPathListBox  matlab.ui.control.ListBox
        AvailableSETfilesinCurrentPathListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        spac % reserved for controller class
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.spac = spaController();
            app.StatusTextArea.Value = sprintf("%s", app.StatusTextArea.Value{1}, "Created Controller Class");
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            %create a dummy figure so that uigetdir doesn't minimize our GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            app.CurrentFolderEditField.Value = app.spac.openFolder;
            delete(f); %delete the dummy figure
            
            % list files in file table
            app.AvailableSETfilesinCurrentPathListBox.Items = app.spac.current_files;
            EEG = pop_loadset(  'filename', app.AvailableSETfilesinCurrentPathListBox.Items{1}, ...
                'filepath', app.CurrentFolderEditField.Value, ...
                'loadmode','info');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilesPanel = uipanel(app.UIFigure);
            app.FilesPanel.Title = 'Files';
            app.AvailableSETfilesinCurrentPathListBoxLabel = uilabel(app.FilesPanel);
            app.AvailableSETfilesinCurrentPathListBoxLabel.Text = 'Available SET files in Current Path';
            app.AvailableSETfilesinCurrentPathListBox = uilistbox(app.FilesPanel);
            app.LoadedSETfileEEGStructureLabel = uilabel(app.FilesPanel);
            app.LoadedSETfileEEGStructureLabel.Text = 'Loaded SET file EEG Structure';
            app.LoadSelectedSETFileButton = uibutton(app.FilesPanel, 'push');
            app.LoadSelectedSETFileButton.Text = 'Load Selected SET File';
            app.TextArea = uitextarea(app.FilesPanel);
            app.PreProcessingPanel = uipanel(app.UIFigure);
            app.PreProcessingPanel.Title = 'PreProcessing';
            app.PostProcessingPanel = uipanel(app.UIFigure);
            app.PostProcessingPanel.Title = 'PostProcessing';
            app.FeatureGenerationPanel = uipanel(app.UIFigure);
            app.FeatureGenerationPanel.Title = 'Feature Generation';
            app.EEGPreprocessingAnalyzerv1Label = uilabel(app.UIFigure);
            app.EEGPreprocessingAnalyzerv1Label.Text = 'EEG Preprocessing Analyzer v.1';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel = uilabel(app.UIFigure);
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Interpreter = 'html';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Text = '<a href=https://github.com/cincibrainlab/sigPreProcessAnalyzer>https://github.com/cincibrainlab/sigPreProcessAnalyzer</a>';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.CurrentFolderEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder:';
            app.CurrentFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigPreProcessAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end